classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        chooseVPButton              matlab.ui.control.Button
        showpictureButton           matlab.ui.control.Button
        chooseforegroundButton      matlab.ui.control.Button
        goButton                    matlab.ui.control.Button
        choose4pointsButton         matlab.ui.control.Button
        ErrormessageEditField       matlab.ui.control.EditField
        ErrormessageEditFieldLabel  matlab.ui.control.Label
        chooseimageButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Files
        pathstr 
        filep
        namestr
        currentFolder
        nextFolder

        %image
        new_image
        centerpoint_x
        centerpoint_y
        x
        y
        im2
        foreground
        image_old
        
        %mask
        mask_total = {}

        %rectangle
        irx
        iry
        cpx
        cpy
        cpOutx
        cpOuty
        vp_x
        vp_y

        %camera control
        x_max
        z_max
        y_max
        imBottomBlend
        imUpBlend
        imMiddleBlend
        imLeftBlend
        imRightBlend
        z_fore
        max_depth
        imForegroundBlend
        camx
        camy
        camz
        stepx
        stepy
        stepz

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %cla(app.UIAxes,'reset') ;
            
            
            app.filep = mfilename('fullpath');

            [app.pathstr,app.namestr]=fileparts(app.filep);
            app.currentFolder = app.pathstr;
            if app.currentFolder(1,end-3) == '\'
                app.nextFolder = '\lib\';
            else
                app.nextFolder = '/lib/';
            end

            I = imread([app.currentFolder, app.nextFolder,'oil-painting.png']);
            %im.ImageSource = I;

            axis(app.UIAxes,'off');  
            title(app.UIAxes,'');   
            xlabel(app.UIAxes,'');  
            ylabel(app.UIAxes,''); 

            app.new_image = imresize(I,[500 NaN]);
            [app.x,app.y,z] = size(app.new_image);
            app.centerpoint_x = 640/2;
            app.centerpoint_y = 480/2;
            app.UIAxes.Position = [app.centerpoint_x-app.x/2,app.centerpoint_y-app.y/2,app.x,app.y];
            %app.UIAxes.XLim = [0,app.x];
            %app.UIAxes.YLim = [0,app.y];
            imshow(app.new_image,'Parent', app.UIAxes);
            app.im2 = app.new_image;
            %assignin("base","new_image",new_image);
        end

        % Button pushed function: chooseimageButton
        function chooseimageButtonPushed(app, event)


            [imfile,impath] = uigetfile({'*.jpg; *.png;*.ppm','All Image files'},'Choose the Image');
            if impath == 0
                app.ErrormessageEditField.Value = "You haven't choose an image! ";
                im = imread([app.currentFolder, app.nextFolder,'oil-painting.png']);
                app.new_image = imresize(im,[600 NaN]);
                [app.x,app.y,z] = size(app.new_image);
                
                app.UIAxes.Position = [app.centerpoint_x-app.x/2,app.centerpoint_y-app.y/2,app.x,app.y];
                imshow(app.new_image,'Parent', app.UIAxes);
            else
                im = imread([impath,imfile]);
                if app.y>app.x
                    app.new_image = imresize(im,[500 NaN]);
                    [app.x,app.y,z] = size(app.new_image);
                    app.UIAxes.Position = [app.centerpoint_x-app.x/2,app.centerpoint_y-app.y/2,app.x,app.y];
                    imshow(app.new_image,'Parent', app.UIAxes);
                else
                    app.new_image = imresize(im,[NaN 700]);
                    [app.x,app.y,z] = size(app.new_image);
                    app.UIAxes.Position = [app.centerpoint_x-app.x/2,app.centerpoint_y-app.y/2,app.x,app.y];
                    imshow(app.new_image,'Parent', app.UIAxes);
                end
            end
            size(app.new_image)
            app.im2 = app.new_image;

        end

        % Button pushed function: chooseforegroundButton
        function chooseforegroundButtonPushed(app, event)
            
            
            h = drawpolygon(app.UIAxes)
            mask = createMask(h);
            app.mask_total{end+1} = mask;
            
           
        end

        % Button pushed function: showpictureButton
        function showpictureButtonPushed(app, event)
            mask_all = zeros(app.x,app.y);
            for i = 1:size(app.mask_total,2)
                mask_all = mask_all + app.mask_total{i};
            end
            mask_all = logical(mask_all);
            % filling
            app.im2 = inpaintExemplar(app.new_image,mask_all,'FillOrder','tensor','PatchSize',18);
            imshow(app.im2,'Parent', app.UIAxes);
            mask2=-1*(mask_all-1);
            im = im2double(app.new_image);
            app.foreground = uint8(255*mask_all.*im);
            app.foreground = app.foreground+uint8(255*mask2);
        end

        % Button pushed function: choose4pointsButton
        function choose4pointsButtonPushed(app, event)
            if isempty(app.mask_total)
                mask_all = zeros(app.x,app.y);
                mask_all(1:2,1:2,:) = 1;
                mask_all = logical(mask_all);
                app.im2 = inpaintExemplar(app.new_image,mask_all,'FillOrder','tensor','PatchSize',18);
                imshow(app.im2,'Parent', app.UIAxes);
                mask2=-1*(mask_all-1);
                im = im2double(app.new_image);
                app.foreground = uint8(255*mask_all.*im);
                app.foreground = app.foreground+uint8(255*mask2);
            end
            
            try
                imshow(app.im2,'Parent', app.UIAxes);   
                % get the points
                app.UIFigure.HandleVisibility = 'callback';
                [rxnew,rynew,button] = ginput(4);
            

                rx = rxnew; ry=rynew;

                % draw the rectangle
                hold on;
                % round:get integer
                app.irx = round([rx(1) rx(2) rx(3) rx(4) rx(1)]);
                app.iry =  round([ry(1) ry(2) ry(3) ry(4) ry(1)]);
                % draw the rectangle
                plot(app.irx,app.iry,'Color','r','LineStyle','-'); 
                hold off;
                drawnow;   
                app.UIFigure.HandleVisibility = 'off';
            catch error
                app.ErrormessageEditField.Value = "You haven't chosen the points! Please do this step again!";
            end


        end

        % Button pushed function: chooseVPButton
        function chooseVPButtonPushed(app, event)
            try
                [max_y, max_x, c] = size(app.im2);
                imshow(app.im2,'Parent', app.UIAxes);   
                app.UIFigure.HandleVisibility = 'callback';
                hold on;
                plot(app.irx,app.iry,'Color','b','LineStyle','-'); 
                hold off; 
                [vx, vy, button] = ginput(1);
    
                app.vp_x = round(vx(1)); app.vp_y=round(vy(1));

    
                [app.cpx(1), app.cpy(1)] = compute_corner(app.vp_x, app.vp_y, app.irx(1), app.iry(1), 1, 1); 
                [app.cpx(2), app.cpy(2)] = compute_corner(app.vp_x, app.vp_y, app.irx(2), app.iry(2), max_x, 1); 
                [app.cpx(3), app.cpy(3)] = compute_corner(app.vp_x, app.vp_y, app.irx(3), app.iry(3), max_x, max_y); 
                [app.cpx(4), app.cpy(4)] = compute_corner(app.vp_x, app.vp_y, app.irx(4), app.iry(4), 1, max_y); 
                app.cpx = round(app.cpx);
                app.cpy = round(app.cpy);

                % get the point on edge of image
                app.cpOutx = [0 0 0 0];
                app.cpOuty = [0 0 0 0];
                [app.cpOutx(1), app.cpOuty(1)] = compute_outCorner(app.vp_x, app.vp_y, app.irx(1), app.iry(1), 1, 1); 
                [app.cpOutx(2), app.cpOuty(2)] = compute_outCorner(app.vp_x, app.vp_y, app.irx(2), app.iry(2), max_x, 1); 
                [app.cpOutx(3), app.cpOuty(3)] = compute_outCorner(app.vp_x, app.vp_y, app.irx(3), app.iry(3), max_x, max_y); 
                [app.cpOutx(4), app.cpOuty(4)] = compute_outCorner(app.vp_x, app.vp_y, app.irx(4), app.iry(4), 1, max_y); 
                app.cpOutx = round(app.cpOutx);
                app.cpOuty = round(app.cpOuty);

                hold on;
                plot(app.irx,app.iry,'b'); 
                plot([app.vp_x app.cpx(1)],[app.vp_y app.cpy(1)],'r');
                plot([app.vp_x app.cpx(2)],[app.vp_y app.cpy(2)],'r');
                plot([app.vp_x app.cpx(3)],[app.vp_y app.cpy(3)],'r');
                plot([app.vp_x app.cpx(4)],[app.vp_y app.cpy(4)],'r');
                hold off;
                app.UIFigure.HandleVisibility = 'off';
            catch error
                app.ErrormessageEditField.Value = "You haven't chosen the point! Please do this step again!";
            end
      
            
        end

        % Button pushed function: goButton
        function goButtonPushed(app, event)
            %im_new = zeros(app.x,app.y,3);
            %imshow(im_new,'Parent', app.UIAxes); 
            cla(app.UIAxes) ;
            [app.x_max,app.z_max,app.y_max,app.imBottomBlend,app.imUpBlend,app.imMiddleBlend,app.imLeftBlend,app.imRightBlend,app.z_fore,app.max_depth,app.imForegroundBlend]=face_genrator(app.new_image,app.vp_x,app.vp_y,app.irx,app.iry,app.foreground,app.im2,app.cpOutx,app.cpOuty,app.cpx,app.cpy);
            %imshow(app.imRightBlend)
            app.UIFigure.HandleVisibility = 'callback';
            
            %view(app.UIAxes,[-1 1 2.5]) 
            % create the surface and texturemap it with a given image
            hold on;
            % bottom
%             surface(app.UIAxes,[0 app.x_max; 0 app.x_max], [0 0; app.z_max app.z_max], [0 0; 0 0], ...
%                 'FaceColor', 'texturemap', 'CData', flip(app.imBottomBlend,1),'EdgeAlpha',0);
              surface(app.UIAxes,[0 app.x_max; 0 app.x_max], [0 0; app.z_max app.z_max], [0 0; 0 0], ...
                  'FaceColor', 'texturemap', 'CData', flip(flip(app.imBottomBlend,1),2),'EdgeAlpha',0);
            % top
%             surface(app.UIAxes,[0 app.x_max; 0 app.x_max], [0 0; app.z_max app.z_max], [app.y_max app.y_max; app.y_max app.y_max], ...
%                 'FaceColor', 'texturemap', 'CData', app.imUpBlend, 'EdgeAlpha',0);
              surface(app.UIAxes,[0 app.x_max; 0 app.x_max], [0 0; app.z_max app.z_max], [app.y_max app.y_max; app.y_max app.y_max], ...
                'FaceColor', 'texturemap', 'CData', flip(app.imUpBlend,2), 'EdgeAlpha',0);
            % back
%             surface(app.UIAxes,[0 app.x_max; 0 app.x_max], [app.z_max app.z_max; app.z_max app.z_max], [0 0; app.y_max app.y_max], ...
%                 'FaceColor', 'texturemap', 'CData', flip(app.imMiddleBlend,1),'EdgeAlpha',0);
             surface(app.UIAxes,[0 app.x_max; 0 app.x_max], [app.z_max app.z_max; app.z_max app.z_max], [0 0; app.y_max app.y_max], ...
                 'FaceColor', 'texturemap', 'CData', flip(flip(app.imMiddleBlend,1),2),'EdgeAlpha',0);
            % left
%             surface(app.UIAxes,[0 0; 0 0], [0 app.z_max; 0 app.z_max], [0 0; app.y_max app.y_max], ...
%                 'FaceColor', 'texturemap', 'CData', flip(app.imLeftBlend,1),'EdgeAlpha',0);
              surface(app.UIAxes,[0 0; 0 0], [0 app.z_max; 0 app.z_max], [0 0; app.y_max app.y_max], ...
                  'FaceColor', 'texturemap', 'CData', flip(app.imRightBlend,1),'EdgeAlpha',0);
            % right
%             surface(app.UIAxes,[app.x_max app.x_max; app.x_max app.x_max], [0 app.z_max; 0 app.z_max], [0 0; app.y_max app.y_max], ...
%                 'FaceColor', 'texturemap', 'CData', flip(flip(app.imRightBlend,1),2),'EdgeAlpha',0);
              surface(app.UIAxes,[app.x_max app.x_max; app.x_max app.x_max], [0 app.z_max; 0 app.z_max], [0 0; app.y_max app.y_max], ...
                  'FaceColor', 'texturemap', 'CData', flip(app.imLeftBlend,1),'EdgeAlpha',0);

            if app.z_fore>0 && app.z_fore<app.max_depth
                logical = (app.imForegroundBlend(:,:,1)~=1).*255;
                foreground_handle = surface([app.x_max 0 ;app.x_max 0 ], [app.z_fore app.z_fore; app.z_fore app.z_fore], [0 0; app.y_max app.y_max], ...
                    'FaceColor', 'texturemap', 'CData', flip(app.imForegroundBlend,1),'EdgeAlpha',0);
    
                foreground_handle.FaceAlpha = 'texturemap';
                foreground_handle.AlphaData = flip(logical,1);
            end
            axis equal;  % make X,Y,Z dimentions be equal
            axis vis3d;  % freeze the scale for better rotations
            axis off;    % turn off the stupid tick marks
            camproj('perspective');  % make it a perspective projection

                % set camera position
            app.camx = app.x_max/2 ;
            app.camy = -(app.z_max +500);
            app.camz = app.y_max/2;


            app.stepx = 0.05;
            app.stepy = 0.05;
            app.stepz = 0.05;

            % set camera on ground
            camup([0,0,1]);
            campos([app.camx app.camy app.camz]);
            camva(45);

            app.UIFigure.HandleVisibility = 'off';
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
             %% camera
                % Some 3D magic...
                app.UIFigure.HandleVisibility = 'callback';
           %hold on;     
                %waitforbuttonpress;
            key = event.Key;


           switch key
                %  change camera angle
                case {'d','D'}
                    camdolly(-app.stepx,0,0,'fixtarget');           
                case {'a','A'}
                    camdolly(app.stepx,0,0,'fixtarget');
                case {'s','S'}
                    camdolly(0,app.stepy,0,'fixtarget');
                case {'w','W'}
                    camdolly(0,-app.stepy,0,'fixtarget');
                case {'q','Q'}
                    camdolly(0,0,app.stepz,'fixtarget');
                case {'e','E'}
                    camdolly(0,0,-app.stepz,'fixtarget');

               %change camera position
                case {'h','H'}
                    camdolly(-app.stepx,0,0);
                case {'k','K'}
                    camdolly(app.stepx,0,0);
                case {'u','U'}
                    camdolly(0,app.stepy,0);
                case {'j','J'}
                    camdolly(0,-app.stepy,0);    
              % Screenshot
                case {'b','B'}
                    f = getframe(gcf);
                    f = frame2im(f);
                    imwrite(f,'Screenshot.jpg');
                    myicon = imread('Screenshot.jpg');
                    msg = msgbox('Screenshot  successfully!!','msg','custom', myicon);
                    uiwait(msg)
            % Use esc to turn off keyboard control
%                case 27
%                     break;
            
            end
%             key = event.Key;
%             pause(.001);
            
      %end


           %hold off;
           app.UIFigure.HandleVisibility = 'off';
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.chooseimageButton = uibutton(app.UIFigure, 'push');
            app.chooseimageButton.ButtonPushedFcn = createCallbackFcn(app, @chooseimageButtonPushed, true);
            app.chooseimageButton.Text = {'choose'; ' image'};
            app.ErrormessageEditFieldLabel = uilabel(app.UIFigure);
            app.ErrormessageEditFieldLabel.Text = 'Error message';
            app.ErrormessageEditField = uieditfield(app.UIFigure, 'text');
            app.choose4pointsButton = uibutton(app.UIFigure, 'push');
            app.choose4pointsButton.ButtonPushedFcn = createCallbackFcn(app, @choose4pointsButtonPushed, true);
            app.choose4pointsButton.Text = {'choose '; '4 points'};
            app.goButton = uibutton(app.UIFigure, 'push');
            app.goButton.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.goButton.Text = 'go';
            app.chooseforegroundButton = uibutton(app.UIFigure, 'push');
            app.chooseforegroundButton.ButtonPushedFcn = createCallbackFcn(app, @chooseforegroundButtonPushed, true);
            app.chooseforegroundButton.Text = {'choose'; 'foreground'};
            app.showpictureButton = uibutton(app.UIFigure, 'push');
            app.showpictureButton.ButtonPushedFcn = createCallbackFcn(app, @showpictureButtonPushed, true);
            app.showpictureButton.Text = {'show '; 'picture '};
            app.chooseVPButton = uibutton(app.UIFigure, 'push');
            app.chooseVPButton.ButtonPushedFcn = createCallbackFcn(app, @chooseVPButtonPushed, true);
            app.chooseVPButton.Text = {'choose'; ' VP'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end