classdef app1_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ErrormessageEditField       matlab.ui.control.EditField
        ErrormessageEditFieldLabel  matlab.ui.control.Label
        chooseimageButton           matlab.ui.control.Button
        CenterPanel                 matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app)
            filep = mfilename('fullpath');
            [pathstr,namestr]=fileparts(filep);
            currentFolder = pathstr;
            if currentFolder(1,end-3) == '\'
                nextFolder = '\lib\';
            else
                nextFolder = '/lib/';
            end
            I = imread([currentFolder, nextFolder,'metro-station.png']);
            
            axis(app.UIAxes,'off');  
            title(app.UIAxes,'');   
            xlabel(app.UIAxes,'');  
            ylabel(app.UIAxes,'');   
            new_image = imresize(I,[480 NaN]);
            [x,y,z] = size(new_image);
            centerpoint_x = 640/2;
            centerpoint_y = 480/2;
            app.UIAxes.Position = [centerpoint_x-x/2,centerpoint_y-y/2+50,x,y];
            imshow(new_image,'Parent', app.UIAxes);
        end

        % Button pushed function: chooseimageButton
        function chooseimageButtonPushed(app, event)
            filep = mfilename('fullpath');
            [pathstr,namestr]=fileparts(filep);
            currentFolder = pathstr;
            if currentFolder(1,end-3) == '\'
                nextFolder = '\lib\';
            else
                nextFolder = '/lib/';
            end
            cd([currentFolder, nextFolder]); 

            [imfile,impath] = uigetfile({'*.jpg; *.png;*.ppm','All Image files'},'Choose the Image');
            if impath == 0
                app.commentTextArea.Value = "You haven't choose an image! ";
                im = imread("metro-station.png");
                new_image = imresize(im,[480 NaN]);
                [x,y,z] = size(new_image);
                centerpoint_x = 640/2;
                centerpoint_y = 480/2;
                app.UIAxes.Position = [centerpoint_x-x/2,centerpoint_y-y/2+50,x,y];
                imshow(new_image,'Parent', app.UIAxes);
            else
                im = imread([impath,imfile]);
                [x,y,z] = size(im);
                centerpoint_x = 640/2;
                centerpoint_y = 480/2;
                if x>y
                    new_image = imresize(im,[480 NaN]);
                    [x,y,z] = size(new_image);
                    app.UIAxes.Position = [centerpoint_x-x/2,centerpoint_y-y/2+50,x,y];
                    imshow(new_image,'Parent', app.UIAxes);
                else
                    new_image = imresize(im,[NaN 640]);
                    [x,y,z] = size(new_image);
                    app.UIAxes.Position = [centerpoint_x-x/2,centerpoint_y-y/2+50,x,y];
                    imshow(new_image,'Parent', app.UIAxes);
                end
            end
            cd ..
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            imshow([currentFolder, nextFolder,'metro-station.png'],'Parent', app.UIAxes);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {154, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.chooseimageButton = uibutton(app.LeftPanel, 'push');
            app.chooseimageButton.ButtonPushedFcn = createCallbackFcn(app, @chooseimageButtonPushed, true);
            app.chooseimageButton.Text = 'choose image';
            app.ErrormessageEditFieldLabel = uilabel(app.LeftPanel);
            app.ErrormessageEditFieldLabel.Text = 'Error message';
            app.ErrormessageEditField = uieditfield(app.LeftPanel, 'text');
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end